classdef flow_1d < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ButtonGroup           matlab.ui.container.ButtonGroup
        Button2               matlab.ui.control.ToggleButton
        Button                matlab.ui.control.ToggleButton
        phiEditField          matlab.ui.control.NumericEditField
        phiEditFieldLabel     matlab.ui.control.Label
        KEditField            matlab.ui.control.NumericEditField
        KEditFieldLabel       matlab.ui.control.Label
        PauseButton           matlab.ui.control.Button
        P_inkPaSlider         matlab.ui.control.Slider
        P_inkPaSliderLabel    matlab.ui.control.Label
        muPasSlider           matlab.ui.control.Slider
        muPasSliderLabel      matlab.ui.control.Label
        D_pmmSlider           matlab.ui.control.Slider
        D_pmmSliderLabel      matlab.ui.control.Label
        V_pmm3Slider          matlab.ui.control.Slider
        Km2Label              matlab.ui.control.Label
        LmSlider              matlab.ui.control.Slider
        LmSliderLabel         matlab.ui.control.Label
        StartAnimationButton  matlab.ui.control.Button
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fibreCircles          % circle handles
        fibreSpacingX = 0.01; % m
        fibreSpacingY = 0.01; % m
        halfHeight = 0.2; % m, half-height of composite section
        IsRunning = false;      % Tracks if the animation is active
        RestartRequested = false; % Flag to signal a restart
        IsPaused = false; % true when paused
    end
    
    methods (Access = private)
        
        % draw and update fibres
        function updateFibres(app)

            new_phi = app.D_pmmSlider.Value;
            
            L = app.LmSlider.Value;
            cla(app.UIAxes);
            
            % redraw composite section
            rectangle(app.UIAxes, 'Position', [0, -app.halfHeight, L, 2*app.halfHeight], 'FaceColor', [0.8 0.8 0.8], 'EdgeColor', 'k', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            box(app.UIAxes, 'on');
            set(app.UIAxes, 'YTick', []);

            % calculate number of fibres in X and Y
            numFibresX = floor(L / app.fibreSpacingX);
            numFibresY = floor(0.4 / app.fibreSpacingY);

            % calculate fibre radius based on porosity
            cell_width = L / numFibresX;
            max_radius = cell_width / 2 * 0.9; % 90% of cell half-width
            radius = max_radius * sqrt(1 - new_phi);
            diameter = 2 * radius;

            % draw grid of fibres
            x_centers = linspace(cell_width/2, L - cell_width/2, numFibresX);
            y_centers = linspace(0.18, -0.18, numFibresY);

            app.fibreCircles = gobjects(numFibresX, numFibresY); 
            for i = 1:numFibresX
                for j = 1:numFibresY
                    x_pos = x_centers(i) - radius;
                    y_pos = y_centers(j) - radius;

                    % a circle is a rectangle with curvature 1
                    app.fibreCircles(i,j) = rectangle(app.UIAxes, ...
                        'Position', [x_pos, y_pos, diameter, diameter], ...
                        'Curvature', [1,1], ...
                        'FaceColor', [app.halfHeight app.halfHeight app.halfHeight], ...
                        'EdgeColor', 'none');
                end
            end
            
            title(app.UIAxes, 'Adjust sliders and press Start');
            xlabel(app.UIAxes, 'Position along composite (m)');
            ylabel(app.UIAxes, ' ');
            hold(app.UIAxes, 'off');
            axis(app.UIAxes, 'equal')
        end

        % Callback for the porosity slider changing
        function phiSliderChanging(app)
            updateFibres(app);
        end

        % Callback for the length slider changing
        function LmSliderChanging(app)
            updateFibres(app); % Redraw fibres for new length
        end
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateFibres(app);
        end

        % Button pushed function: StartAnimationButton
        function StartAnimationButtonPushed(app, event)
            % restart logic
            % if animation is already running restart it
            if app.IsRunning
                app.RestartRequested = true;
                app.IsPaused          = false;
                app.PauseButton.Text  = 'Pause';
                return;
            end


            % if animation isnt running start it
            app.IsRunning = true;
            app.RestartRequested = false;
            app.StartAnimationButton.Text = 'Restart Animation';
            
            % Composite Properties
            L = app.LmSlider.Value;       % Length of the composite [m]
            K = app.V_pmm3Slider.Value * 1e-12;     % Permeability of the composite [m^2]
            phi = app.D_pmmSlider.Value;     % Porosity of the composite [-]
            
            % Fluid Properties
            mu = app.muPasSlider.Value;      % Viscosity of the fluid [Pa.s]
            
            % Process Conditions
            Pin = app.P_inkPaSlider.Value * 1e3;     % Inlet pressure [Pa]
            Pout = 0;      % Outlet pressure [Pa]
            deltaP = Pin - Pout; % Pressure drop across the composite [Pa]
            
            % Simulation Parameters
            t_total = 1e6; % Total simulation time [s]
            n_steps = 5000; % Number of time steps
            dt = t_total / n_steps; % Time step size [s]
            
            % Darcy's Law
            
            % Calculate Darcy Velocity
            const_term = 2*K*deltaP/mu;
            
            % animation setup
            time_vec = 0:dt:t_total; % Time vector for the simulation
            x_front = zeros(size(time_vec)); % Initialize the position of the flow front
            
            % figure setup
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            box(app.UIAxes, 'on');
            title(app.UIAxes, 'Flow Front Simulation (t = 0.00 s)');
            xlabel(app.UIAxes, 'Position along composite (m)');
            ylabel(app.UIAxes, ' ');
            set(app.UIAxes, 'YTick', []);
            
            % Draw the empty composite
            rectangle(app.UIAxes, 'Position', [0, -app.halfHeight, L, 2*app.halfHeight], 'FaceColor', [0.8 0.8 0.8], 'EdgeColor', 'k', 'LineWidth', 1.5);
            newphi = app.D_pmmSlider.Value;
            updateFibres(app);

            % Draw the initial filled region (blue rectangle)
            filled_region = rectangle(app.UIAxes, 'Position', [0, -app.halfHeight, 0, 2*app.halfHeight], 'FaceColor', [0.3 0.6 1], 'EdgeColor', 'none');
            
            % Draw the flow front (as a vertical red line)
            flow_front_line = line(app.UIAxes, [0 0], [-app.halfHeight app.halfHeight], 'Color', 'r', 'LineWidth', 2);
            uistack(app.fibreCircles(:), 'top');
            uistack(flow_front_line, 'top');

            % main animation loop
            while app.IsRunning
                for i = 2:length(time_vec)
                      
                    % pause check
                     while app.IsPaused && app.IsRunning
                     drawnow;  
                     end
                     % If they hit Stop/Restart, break out
                    if ~app.IsRunning || app.RestartRequested
                        app.RestartRequested = false;
                        break;
                    end

                    x_front(i)=sqrt(const_term*time_vec(i));
    
                    % Update the width of the blue rectangle
                    delete(filled_region);
                    filled_region = rectangle(app.UIAxes, 'Position', [0, -app.halfHeight, x_front(i), 2*app.halfHeight], 'FaceColor', [0.3 0.6 1], 'EdgeColor', 'none', 'FaceAlpha', 0.3);

                    % Update the position of the flow front line
                    delete(flow_front_line);
                    flow_front_line = line(app.UIAxes, [x_front(i) x_front(i)], [-app.halfHeight app.halfHeight], 'Color', 'r', 'LineWidth', 2);
                
                    % Update the title
                    fill_percentage = (x_front(i) / app.LmSlider.Value) * 100;
                    title(app.UIAxes, sprintf('Time: %.2f hrs | Fill: %.1f%%', time_vec(i)/3600, fill_percentage));
                
                    % Pause to control animation speed
                    drawnow
                
                    % Stop the animation if the composite is full 
                    % or if we move a slider
                    if x_front(i) >= app.LmSlider.Value || app.RestartRequested
                        app.RestartRequested = false;
                        break;
                    end
                end
                
                hold(app.UIAxes, 'off');
                disp('Animation complete.');
            end
        end

        % Value changed function: LmSlider
        function LmSliderValueChanged(app, event)
            if app.IsRunning
                app.RestartRequested = true;
                updateFibres(app);
                return;
            end
        end

        % Value changed function: V_pmm3Slider
        function V_pmm3SliderValueChanged(app, event)
            if app.IsRunning
                app.RestartRequested = true;
                updateFibres(app);
                return;
            end
        end

        % Value changed function: D_pmmSlider
        function D_pmmSliderValueChanged(app, event)
            if app.IsRunning
                app.RestartRequested = true;
                updateFibres(app);
                return;
            end
        end

        % Value changed function: muPasSlider
        function muPasSliderValueChanged(app, event)
            if app.IsRunning
                app.RestartRequested = true;
                updateFibres(app);
                return;
            end
        end

        % Value changed function: P_inkPaSlider
        function P_inkPaSliderValueChanged(app, event)
            if app.IsRunning
                app.RestartRequested = true;
                updateFibres(app);
                return;
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
           app.IsPaused = ~app.IsPaused;
           if app.IsPaused
            app.PauseButton.Text = 'Resume';
           else
            app.PauseButton.Text = 'Pause';
           end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.StartAnimationButton = uibutton(app.UIFigure, 'push');
            app.StartAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnimationButtonPushed, true);
            app.StartAnimationButton.Text = 'Start Animation';
            app.LmSliderLabel = uilabel(app.UIFigure);
            app.LmSliderLabel.Text = 'L (m)';
            app.LmSlider = uislider(app.UIFigure);
            app.LmSlider.Limits = [0 1];
            app.LmSlider.ValueChangedFcn = createCallbackFcn(app, @LmSliderValueChanged, true);
            app.LmSlider.Value = 0.5;
            app.Km2Label = uilabel(app.UIFigure);
            app.Km2Label.Text = 'V_p (mm^3)';
            app.V_pmm3Slider = uislider(app.UIFigure);
            app.V_pmm3Slider.Limits = [0 10000];
            app.V_pmm3Slider.ValueChangedFcn = createCallbackFcn(app, @V_pmm3SliderValueChanged, true);
            app.V_pmm3Slider.Value = 5000;
            app.D_pmmSliderLabel = uilabel(app.UIFigure);
            app.D_pmmSliderLabel.Text = 'D_p (mm)';
            app.D_pmmSlider = uislider(app.UIFigure);
            app.D_pmmSlider.Limits = [0 1];
            app.D_pmmSlider.ValueChangedFcn = createCallbackFcn(app, @D_pmmSliderValueChanged, true);
            app.D_pmmSlider.Value = 0.5;
            app.muPasSliderLabel = uilabel(app.UIFigure);
            app.muPasSliderLabel.Text = 'mu (Pa.s)';
            app.muPasSlider = uislider(app.UIFigure);
            app.muPasSlider.Limits = [0 1000];
            app.muPasSlider.ValueChangedFcn = createCallbackFcn(app, @muPasSliderValueChanged, true);
            app.muPasSlider.Value = 500;
            app.P_inkPaSliderLabel = uilabel(app.UIFigure);
            app.P_inkPaSliderLabel.Text = 'P_in (kPa)';
            app.P_inkPaSlider = uislider(app.UIFigure);
            app.P_inkPaSlider.ValueChangedFcn = createCallbackFcn(app, @P_inkPaSliderValueChanged, true);
            app.P_inkPaSlider.Value = 50;
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.phiEditFieldLabel = uilabel(app.UIFigure);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.UIFigure, 'numeric');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uitogglebutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flow_1d

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end